classdef eyeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OfflineEyeTrackingTab           matlab.ui.container.Tab
        RadiusPupilEditFieldLabel       matlab.ui.control.Label
        RadiusPupilEditField            matlab.ui.control.NumericEditField
        RadiusCornealReflectionEditFieldLabel  matlab.ui.control.Label
        RadiusCornealReflectionEditField  matlab.ui.control.NumericEditField
        MinimumFeaturesEditFieldLabel   matlab.ui.control.Label
        MinimumFeaturesEditField        matlab.ui.control.NumericEditField
        radiiCR1EditField_2             matlab.ui.control.NumericEditField
        ImproveImageContrastCheckBox    matlab.ui.control.CheckBox
        SetupEyeTrackingButton          matlab.ui.control.Button
        RunEyeTrackingButton            matlab.ui.control.Button
        edgeThreshEditFieldLabel        matlab.ui.control.Label
        edgeThreshEditField             matlab.ui.control.NumericEditField
        PlotAnalysisCheckBox            matlab.ui.control.CheckBox
        debugCheckBox                   matlab.ui.control.CheckBox
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        RadiusPupilEditField_2          matlab.ui.control.NumericEditField
        CameraDropDownLabel             matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        UseallthreshCheckBox            matlab.ui.control.CheckBox
        Folder_EyeTrackingTab           matlab.ui.control.EditField
        FolderButton                    matlab.ui.control.Button
        UIAxes3_3                       matlab.ui.control.UIAxes
        EyeAnalysisTab                  matlab.ui.container.Tab
        CalibrationStimFrequencyEditFieldLabel  matlab.ui.control.Label
        CalibrationStimFrequencyEditField  matlab.ui.control.NumericEditField
        LeftEyeCheckBox                 matlab.ui.control.CheckBox
        Camera2TimestampsCheckBox       matlab.ui.control.CheckBox
        Step1DesaccadeforEyeChannelScaleFactorsPanel  matlab.ui.container.Panel
        GetScaleFactorsButton           matlab.ui.control.Button
        SaccadeWindowEditField_2        matlab.ui.control.NumericEditField
        SaccadeThresholdVideoEditFieldLabel  matlab.ui.control.Label
        SaccadeThresholdVideoEditField  matlab.ui.control.NumericEditField
        SaccadeWindowmsEditFieldLabel   matlab.ui.control.Label
        SaccadeWindowmsEditField        matlab.ui.control.NumericEditField
        SaccadeThresholdMagnetChan1EditFieldLabel  matlab.ui.control.Label
        SaccadeThresholdMagnetChan1EditField  matlab.ui.control.NumericEditField
        MinimumGoodDataLengthEditFieldLabel  matlab.ui.control.Label
        MinimumGoodDataLengthEditField  matlab.ui.control.NumericEditField
        SaccadeThresholdMagnetChan2EditFieldLabel  matlab.ui.control.Label
        SaccadeThresholdMagnetChan2EditField  matlab.ui.control.NumericEditField
        STEP2AlignDataMagnetandVideoPanel  matlab.ui.container.Panel
        AlignDataLinearityMethodButton  matlab.ui.control.Button
        AlignDataSaccadeOverlapMethodButton  matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Magnet1PositionButton           matlab.ui.control.ToggleButton
        Magnet1VelocityButton           matlab.ui.control.ToggleButton
        Magnet2PositionButton           matlab.ui.control.ToggleButton
        Magnet2VelocityButton           matlab.ui.control.ToggleButton
        CustomButton                    matlab.ui.control.ToggleButton
        EditField                       matlab.ui.control.NumericEditField
        ShiftValueLabel                 matlab.ui.control.Label
        ShiftValueLabel_2               matlab.ui.control.Label
        ShiftValueLabel_3               matlab.ui.control.Label
        ShiftValueLabel_4               matlab.ui.control.Label
        PLOT_AlignedTraces_Button       matlab.ui.control.Button
        AdvancedLinearityButton         matlab.ui.control.Button
        FolderEditField                 matlab.ui.control.EditField
        FolderButton_2                  matlab.ui.control.Button
        UIAxes4_3                       matlab.ui.control.UIAxes
        UIAxes4_4                       matlab.ui.control.UIAxes
        STEP3ChooseBestMagnetButtonGroup  matlab.ui.container.ButtonGroup
        MagnetChannel1Button            matlab.ui.control.ToggleButton
        MagnetChannel2Button            matlab.ui.control.ToggleButton
        PLOT_DesaccededTraces_Button    matlab.ui.control.Button
        PLOT_FramerateHistogram_Button  matlab.ui.control.Button
        SAVEButton                      matlab.ui.control.Button
    end

    properties (Access = private)
        vars; % Description
        trackParams;
        frameData = [];
    end
    methods (Access = private)

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.FolderEditField.Value = cd;
            app.Folder_EyeTrackingTab.Value = cd;
            app.vars.Folder = app.Folder_EyeTrackingTab.Value;
            
        end

        % Button pushed function: SetupEyeTrackingButton
        function SetupEyeTrackingButtonPushed(app, event)
            cla(app.UIAxes2_2)
            
            app.trackParams.cam = app.CameraDropDown.Value;
            app.trackParams.radiiPupil = [app.RadiusPupilEditField.Value app.RadiusPupilEditField_2.Value];
            app.trackParams.radiiCR = [app.RadiusCornealReflectionEditField.Value app.radiiCR1EditField_2.Value];
            app.trackParams.minfeatures = app.MinimumFeaturesEditField.Value;
            app.trackParams.imAdjust = app.ImproveImageContrastCheckBox.Value;
            app.trackParams.edgeThresh = app.edgeThreshEditField.Value;
            app.trackParams.plotall = app.PlotAnalysisCheckBox.Value;
            app.trackParams.debugOn = app.debugCheckBox.Value;
            app.trackParams.CRthresh1 = 10;
            app.trackParams.CRthresh = 10;
            app.trackParams.CRthresh2 = 10;
            app.trackParams.CRfilter1 = 6;
            app.trackParams.CRfilter2 = 6;
            app.trackParams.CRfilter = 6;
            app.trackParams.manual = 1;
            app.trackParams.plotOn = 1;
            
            try
                app.trackParams = setupEyeTracking_APP(app, app.trackParams);
            catch
                disp('Unable to detect pupils with given parameters')
                disp('Please try again')
            end
            
        end

        % Button pushed function: RunEyeTrackingButton
        function RunEyeTrackingButtonPushed(app, event)
            app.trackParams.cam = app.CameraDropDown.Value;
            app.trackParams.radiiPupil = [app.RadiusPupilEditField.Value app.RadiusPupilEditField_2.Value];
            app.trackParams.radiiCR = [app.RadiusCornealReflectionEditField.Value app.radiiCR1EditField_2.Value];
            app.trackParams.minfeatures = app.MinimumFeaturesEditField.Value;
            app.trackParams.imAdjust = app.ImproveImageContrastCheckBox.Value;
            app.trackParams.edgeThresh = app.edgeThreshEditField.Value;
            app.trackParams.plotall = app.PlotAnalysisCheckBox.Value;
            app.trackParams.debugOn = app.debugCheckBox.Value;
            app.trackParams.plotOn = 0;
            
            [app.trackParams, app.frameData] = eyeAnalysis_APP(app, app.trackParams);
            
        end

        % Button pushed function: GetScaleFactorsButton
        function GetScaleFactorsButtonPushed(app, event)
            app.vars.CaliStimFreq = app.CalibrationStimFrequencyEditField.Value;
            
            app.vars = getScaleFactors_APP(app, app.vars);
        end

        % Button pushed function: PLOT_DesaccededTraces_Button
        function PLOT_DesaccededTraces_ButtonPushed(app, event)
             app.vars = PLOT_DesaccadedTraces(app.vars);
        end

        % Button pushed function: PLOT_FramerateHistogram_Button
        function PLOT_FramerateHistogram_ButtonPushed(app, event)
            app.vars = PLOT_FramerateHistogram(app, app.vars);
        end

        % Button pushed function: PLOT_AlignedTraces_Button
        function PLOT_AlignedTraces_ButtonPushed(app, event)
            % return if user hasn't calculated shift values yet.
            if contains(app.ShiftValueLabel.Text, 'Shift Value')
                return
            elseif contains(app.ShiftValueLabel.Text, 'Calculating')
                return
            end
            
            % get the alignment value
            if app.Magnet1PositionButton.Value
                app.vars.chosenShiftVal = str2double(app.ShiftValueLabel.Text);
            elseif app.Magnet1VelocityButton.Value
                app.vars.chosenShiftVal = str2double(app.ShiftValueLabel_2.Text);
            elseif app.Magnet2PositionButton.Value
                app.vars.chosenShiftVal = str2double(app.ShiftValueLabel_3.Text);
            elseif app.Magnet2VelocityButton.Value
                app.vars.chosenShiftVal = str2double(app.ShiftValueLabel_4.Text);
            else 
                app.vars.chosenShiftVal = app.EditField.Value;
            end
            
            % make plots
            app.vars = PLOT_AlignedTraces(app.vars);
        end

        % Callback function
        function DoneSetupButtonPushed(app, event)
            app.vars.videoSetupComplete = 1;
        end

        % Value changed function: Folder_EyeTrackingTab
        function Folder_EyeTrackingTabValueChanged(app, event)
            folder = app.Folder_EyeTrackingTab.Value;
            app.FolderEditField.Value = folder;
            app.Folder_EyeTrackingTab.Value = folder;
            app.vars.Folder = folder;
            try
                cd(folder)
            catch 
                disp('Folder Not Found!')
            end
        end

        % Value changed function: FolderEditField
        function Folder_EditFieldValueChanged(app, event)
            folder = app.FolderEditField.Value;
            app.FolderEditField.Value = folder;
            app.Folder_EyeTrackingTab.Value = folder;
            app.vars.Folder = folder;
            try
                cd(folder)
            catch 
                disp('Folder Not Found!')
            end
        end

        % Button pushed function: FolderButton, FolderButton_2
        function Folder_ButtonPushed(app, event)
            folder = uigetdir(app.vars.Folder);
            app.FolderEditField.Value = folder;
            app.Folder_EyeTrackingTab.Value = folder;
            app.vars.Folder = folder;
            cd(folder)
        end

        % Button pushed function: AlignDataLinearityMethodButton
        function AlignData_LinearityMethodButtonPushed(app, event)
            % Add values to the GUI
            app.ShiftValueLabel.Text   = 'Calculating...';
            app.ShiftValueLabel_2.Text = 'Calculating...';
            app.ShiftValueLabel_3.Text = 'Calculating...';
            app.ShiftValueLabel_4.Text = 'Calculating...';          
            pause(1)
            % Calculate the linearity magnet/video
            try
                % kinda hacky, check if you've already calculated it.
                a = app.vars.mag1.pos_linearity_r2;
                disp('Already Calculated')
            catch 
                app.vars = calc_linearity_APP(app.vars);
                disp('Done')
            end
            
            % Add values to the GUI
            app.ShiftValueLabel.Text   = num2str(app.vars.mag1.pos_linearity_maxr2Loc);
            app.ShiftValueLabel_2.Text = num2str(app.vars.mag1.vel_linearity_maxr2Loc);
            app.ShiftValueLabel_3.Text = num2str(app.vars.mag2.pos_linearity_maxr2Loc);
            app.ShiftValueLabel_4.Text = num2str(app.vars.mag2.vel_linearity_maxr2Loc);         
            
        end

        % Button pushed function: 
        % AlignDataSaccadeOverlapMethodButton
        function AlignData_SaccadeOverlapMethodButtonPushed(app, event)
            app.vars = alignmentMethod_Saccade_APP(app, app.vars);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            disp('Saving...')
           
            [~, filenameroot]= fileparts(cd);
            load(fullfile(cd, [filenameroot '.mat']))
            
            % Save the alignment value
            if app.Magnet1PositionButton.Value
                CamAlignVal = str2double(app.ShiftValueLabel.Text);
            elseif app.Magnet1VelocityButton.Value
                CamAlignVal = str2double(app.ShiftValueLabel_2.Text);
            elseif app.Magnet2PositionButton.Value
                CamAlignVal = str2double(app.ShiftValueLabel_3.Text);
            elseif app.Magnet2VelocityButton.Value
                CamAlignVal = str2double(app.ShiftValueLabel_4.Text);
            else 
                CamAlignVal = app.EditField.Value;
            end
            
            % Save the Channel Selection
            scaleCh1_Original = app.vars.scaleCh1;
            scaleCh2_Original = app.vars.scaleCh2;
            
            if app.MagnetChannel1Button.Value
                scaleCh2 = 0;
                scaleCh1 = app.vars.scaleCh1;
            else
                scaleCh1 = 0;
                scaleCh2 = app.vars.scaleCh2;
            end

            save(fullfile(cd, [filenameroot '.mat']), ...
                'scaleCh1', 'scaleCh2',...
                'scaleCh1_Original', 'scaleCh2_Original',...
                'vidAmp',...
                'mag1Amp','mag1Phase',...
                'mag2Amp','mag2Phase',...
                'r2mag1','r2mag2','r2vid',...
                'threshVid','threshMagChan1','threshMagChan2', ...
                'freq', 'CamAlignVal');
            
            disp('Done!')
        end

        % Button pushed function: AdvancedLinearityButton
        function AdvancedLinearityButtonPushed(app, event)
            app.vars = PLOT_linearity(app.vars);
        end

        % Callback function
        function CancelButtonPushed(app, event)
            clear;clc;close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OfflineEyeTrackingTab = uitab(app.TabGroup);
            app.OfflineEyeTrackingTab.Title = 'Offline Eye Tracking';
            app.RadiusPupilEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusPupilEditFieldLabel.Text = 'Radius: Pupil';
            app.RadiusPupilEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusPupilEditField.Tooltip = {'radius in pixels'};
            app.RadiusPupilEditField.Value = 30;
            app.RadiusCornealReflectionEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusCornealReflectionEditFieldLabel.Text = 'Radius: Corneal Reflection ';
            app.RadiusCornealReflectionEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusCornealReflectionEditField.Value = 6;
            app.MinimumFeaturesEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.MinimumFeaturesEditFieldLabel.Text = 'Minimum Features';
            app.MinimumFeaturesEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.MinimumFeaturesEditField.Tooltip = {'Fraction of features needed for pupil detection'};
            app.MinimumFeaturesEditField.Value = 0.5;
            app.radiiCR1EditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.radiiCR1EditField_2.Value = 12;
            app.ImproveImageContrastCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.ImproveImageContrastCheckBox.Text = 'Improve Image Contrast';
            app.ImproveImageContrastCheckBox.Value = true;
            app.SetupEyeTrackingButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.SetupEyeTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @SetupEyeTrackingButtonPushed, true);
            app.SetupEyeTrackingButton.Text = {'Setup'; 'Eye Tracking'; ''};
            app.RunEyeTrackingButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.RunEyeTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @RunEyeTrackingButtonPushed, true);
            app.RunEyeTrackingButton.Text = {'Run'; 'Eye Tracking'};
            app.edgeThreshEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.edgeThreshEditFieldLabel.Text = 'edgeThresh';
            app.edgeThreshEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.edgeThreshEditField.Tooltip = {'Initial gradient threshold of pupil edge detection for cam 1'};
            app.edgeThreshEditField.Value = 35;
            app.PlotAnalysisCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.PlotAnalysisCheckBox.Text = 'Plot Analysis';
            app.PlotAnalysisCheckBox.Value = true;
            app.debugCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.debugCheckBox.Text = 'debug';
            app.UIAxes2_2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes3 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3_2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.XTickLabel = '';
            app.UIAxes3_2.YTick = [];
            app.UIAxes3_2.YTickLabel = '';
            app.RadiusPupilEditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusPupilEditField_2.Tooltip = {'radius in pixels'};
            app.RadiusPupilEditField_2.Value = 70;
            app.CameraDropDownLabel = uilabel(app.OfflineEyeTrackingTab);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.OfflineEyeTrackingTab);
            app.CameraDropDown.Items = {'1', '2'};
            app.CameraDropDown.Value = '1';
            app.UseallthreshCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.UseallthreshCheckBox.Text = 'Use all thresh ';
            app.Folder_EyeTrackingTab = uieditfield(app.OfflineEyeTrackingTab, 'text');
            app.Folder_EyeTrackingTab.ValueChangedFcn = createCallbackFcn(app, @Folder_EyeTrackingTabValueChanged, true);
            app.FolderButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.UIAxes3_3 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes3_3, '')
            xlabel(app.UIAxes3_3, '')
            ylabel(app.UIAxes3_3, '')
            app.UIAxes3_3.XTick = [];
            app.UIAxes3_3.YTick = [];
            app.EyeAnalysisTab = uitab(app.TabGroup);
            app.EyeAnalysisTab.Title = 'Eye Analysis';
            app.CalibrationStimFrequencyEditFieldLabel = uilabel(app.EyeAnalysisTab);
            app.CalibrationStimFrequencyEditFieldLabel.Text = 'Calibration Stim Frequency';
            app.CalibrationStimFrequencyEditField = uieditfield(app.EyeAnalysisTab, 'numeric');
            app.CalibrationStimFrequencyEditField.Value = 1;
            app.LeftEyeCheckBox = uicheckbox(app.EyeAnalysisTab);
            app.LeftEyeCheckBox.Tooltip = {'If unchecked'; ' use right eye'};
            app.LeftEyeCheckBox.Text = 'Left Eye';
            app.LeftEyeCheckBox.Value = true;
            app.Camera2TimestampsCheckBox = uicheckbox(app.EyeAnalysisTab);
            app.Camera2TimestampsCheckBox.Tooltip = {'If unchecked'; ' use camera 1'};
            app.Camera2TimestampsCheckBox.Text = 'Camera 2 Timestamps';
            app.Camera2TimestampsCheckBox.Value = true;
            app.Step1DesaccadeforEyeChannelScaleFactorsPanel = uipanel(app.EyeAnalysisTab);
            app.Step1DesaccadeforEyeChannelScaleFactorsPanel.Title = 'Step 1: Desaccade for Eye Channel Scale Factors';
            app.GetScaleFactorsButton = uibutton(app.Step1DesaccadeforEyeChannelScaleFactorsPanel, 'push');
            app.GetScaleFactorsButton.ButtonPushedFcn = createCallbackFcn(app, @GetScaleFactorsButtonPushed, true);
            app.GetScaleFactorsButton.Text = 'Get Scale Factors';
            app.SaccadeWindowEditField_2 = uieditfield(app.Step1DesaccadeforEyeChannelScaleFactorsPanel, 'numeric');
            app.SaccadeWindowEditField_2.Value = 50;
            app.SaccadeThresholdVideoEditFieldLabel = uilabel(app.Step1DesaccadeforEyeChannelScaleFactorsPanel);
            app.SaccadeThresholdVideoEditFieldLabel.Text = 'Saccade Threshold Video';
            app.SaccadeThresholdVideoEditField = uieditfield(app.Step1DesaccadeforEyeChannelScaleFactorsPanel, 'numeric');
            app.SaccadeThresholdVideoEditField.Value = 500;
            app.SaccadeWindowmsEditFieldLabel = uilabel(app.Step1DesaccadeforEyeChannelScaleFactorsPanel);
            app.SaccadeWindowmsEditFieldLabel.Text = 'Saccade Window (ms)';
            app.SaccadeWindowmsEditField = uieditfield(app.Step1DesaccadeforEyeChannelScaleFactorsPanel, 'numeric');
            app.SaccadeWindowmsEditField.Value = 50;
            app.SaccadeThresholdMagnetChan1EditFieldLabel = uilabel(app.Step1DesaccadeforEyeChannelScaleFactorsPanel);
            app.SaccadeThresholdMagnetChan1EditFieldLabel.Text = 'Saccade Threshold Magnet Chan 1';
            app.SaccadeThresholdMagnetChan1EditField = uieditfield(app.Step1DesaccadeforEyeChannelScaleFactorsPanel, 'numeric');
            app.SaccadeThresholdMagnetChan1EditField.Value = 0.1;
            app.MinimumGoodDataLengthEditFieldLabel = uilabel(app.Step1DesaccadeforEyeChannelScaleFactorsPanel);
            app.MinimumGoodDataLengthEditFieldLabel.Text = 'Minimum Good Data Length';
            app.MinimumGoodDataLengthEditField = uieditfield(app.Step1DesaccadeforEyeChannelScaleFactorsPanel, 'numeric');
            app.MinimumGoodDataLengthEditField.Value = 1;
            app.SaccadeThresholdMagnetChan2EditFieldLabel = uilabel(app.Step1DesaccadeforEyeChannelScaleFactorsPanel);
            app.SaccadeThresholdMagnetChan2EditFieldLabel.Text = 'Saccade Threshold Magnet Chan 2';
            app.SaccadeThresholdMagnetChan2EditField = uieditfield(app.Step1DesaccadeforEyeChannelScaleFactorsPanel, 'numeric');
            app.SaccadeThresholdMagnetChan2EditField.Value = 0.1;
            app.STEP2AlignDataMagnetandVideoPanel = uipanel(app.EyeAnalysisTab);
            app.STEP2AlignDataMagnetandVideoPanel.Title = 'STEP 2 Align Data Magnet and Video';
            app.AlignDataLinearityMethodButton = uibutton(app.STEP2AlignDataMagnetandVideoPanel, 'push');
            app.AlignDataLinearityMethodButton.ButtonPushedFcn = createCallbackFcn(app, @AlignData_LinearityMethodButtonPushed, true);
            app.AlignDataLinearityMethodButton.Text = 'Align Data: Linearity Method';
            app.AlignDataSaccadeOverlapMethodButton = uibutton(app.STEP2AlignDataMagnetandVideoPanel, 'push');
            app.AlignDataSaccadeOverlapMethodButton.ButtonPushedFcn = createCallbackFcn(app, @AlignData_SaccadeOverlapMethodButtonPushed, true);
            app.AlignDataSaccadeOverlapMethodButton.Text = 'Align Data: Saccade Overlap Method';
            app.ButtonGroup = uibuttongroup(app.STEP2AlignDataMagnetandVideoPanel);
            app.Magnet1PositionButton = uitogglebutton(app.ButtonGroup);
            app.Magnet1PositionButton.Text = 'Magnet 1 - Position';
            app.Magnet1PositionButton.Value = true;
            app.Magnet1VelocityButton = uitogglebutton(app.ButtonGroup);
            app.Magnet1VelocityButton.Text = 'Magnet 1 - Velocity';
            app.Magnet2PositionButton = uitogglebutton(app.ButtonGroup);
            app.Magnet2PositionButton.Text = 'Magnet 2 - Position';
            app.Magnet2VelocityButton = uitogglebutton(app.ButtonGroup);
            app.Magnet2VelocityButton.Text = 'Magnet 2 - Velocity';
            app.CustomButton = uitogglebutton(app.ButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.ShiftValueLabel = uilabel(app.ButtonGroup);
            app.ShiftValueLabel.Text = 'Shift Value';
            app.ShiftValueLabel_2 = uilabel(app.ButtonGroup);
            app.ShiftValueLabel_2.Text = 'Shift Value';
            app.ShiftValueLabel_3 = uilabel(app.ButtonGroup);
            app.ShiftValueLabel_3.Text = 'Shift Value';
            app.ShiftValueLabel_4 = uilabel(app.ButtonGroup);
            app.ShiftValueLabel_4.Text = 'Shift Value';
            app.PLOT_AlignedTraces_Button = uibutton(app.STEP2AlignDataMagnetandVideoPanel, 'push');
            app.PLOT_AlignedTraces_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_AlignedTraces_ButtonPushed, true);
            app.PLOT_AlignedTraces_Button.Text = {'Aligned '; 'Traces'};
            app.AdvancedLinearityButton = uibutton(app.STEP2AlignDataMagnetandVideoPanel, 'push');
            app.AdvancedLinearityButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedLinearityButtonPushed, true);
            app.AdvancedLinearityButton.Text = 'Advanced Linearity';
            app.FolderEditField = uieditfield(app.EyeAnalysisTab, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @Folder_EditFieldValueChanged, true);
            app.FolderButton_2 = uibutton(app.EyeAnalysisTab, 'push');
            app.FolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.FolderButton_2.Text = 'Folder';
            app.UIAxes4_3 = uiaxes(app.EyeAnalysisTab);
            title(app.UIAxes4_3, '')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Velocity (deg/s)')
            app.UIAxes4_3.XTick = [];
            app.UIAxes4_3.YTick = [];
            app.UIAxes4_4 = uiaxes(app.EyeAnalysisTab);
            title(app.UIAxes4_4, 'Alignment')
            xlabel(app.UIAxes4_4, '')
            ylabel(app.UIAxes4_4, 'Position')
            app.UIAxes4_4.XTick = [];
            app.UIAxes4_4.YTick = [];
            app.STEP3ChooseBestMagnetButtonGroup = uibuttongroup(app.EyeAnalysisTab);
            app.STEP3ChooseBestMagnetButtonGroup.Title = 'STEP 3: Choose Best Magnet';
            app.MagnetChannel1Button = uitogglebutton(app.STEP3ChooseBestMagnetButtonGroup);
            app.MagnetChannel1Button.Text = 'Magnet Channel 1';
            app.MagnetChannel1Button.Value = true;
            app.MagnetChannel2Button = uitogglebutton(app.STEP3ChooseBestMagnetButtonGroup);
            app.MagnetChannel2Button.Text = 'Magnet Channel 2';
            app.PLOT_DesaccededTraces_Button = uibutton(app.EyeAnalysisTab, 'push');
            app.PLOT_DesaccededTraces_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_DesaccededTraces_ButtonPushed, true);
            app.PLOT_DesaccededTraces_Button.Text = {'Desacceded '; 'Traces'};
            app.PLOT_FramerateHistogram_Button = uibutton(app.EyeAnalysisTab, 'push');
            app.PLOT_FramerateHistogram_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_FramerateHistogram_ButtonPushed, true);
            app.PLOT_FramerateHistogram_Button.Text = {'Framerate '; 'Histogram'};
            app.SAVEButton = uibutton(app.EyeAnalysisTab, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eyeCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end